classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TemperaturainicialSlider        matlab.ui.control.Slider
        TemperaturainicialSliderLabel   matlab.ui.control.Label
        TemperaturadelcontornoSlider    matlab.ui.control.Slider
        TemperaturadelcontornoSliderLabel  matlab.ui.control.Label
        ConstantedelmaterialEditField   matlab.ui.control.NumericEditField
        ConstantedelmaterialEditFieldLabel  matlab.ui.control.Label
        NodoseneltiempoEditField        matlab.ui.control.NumericEditField
        NodoseneltiempoEditFieldLabel   matlab.ui.control.Label
        NodosenelespacioEditField       matlab.ui.control.NumericEditField
        NodosenelespacioEditFieldLabel  matlab.ui.control.Label
        TiempototalSlider               matlab.ui.control.Slider
        TiempototalSliderLabel          matlab.ui.control.Label
        LongituddelasuperficieSlider    matlab.ui.control.Slider
        LongituddelasuperficieSliderLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        L = 1;
        t_max = 2;
        n_x = 11;
        n_t = 9;
        alpha = 0.2;
        T_1j = 70;
        T_i1 = 20;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LongituddelasuperficieSlider
        function LongituddelasuperficieSliderValueChanged(app, event)
            app.L = app.LongituddelasuperficieSlider.Value;
            main2(app.UIAxes, app.UIAxes2, app.L, app.t_max, app.n_x, app.n_t, app.alpha, app.T_1j, app.T_i1)
        end

        % Value changed function: TiempototalSlider
        function TiempototalSliderValueChanged(app, event)
            app.t_max = app.TiempototalSlider.Value;
            main2(app.UIAxes, app.UIAxes2, app.L, app.t_max, app.n_x, app.n_t, app.alpha, app.T_1j, app.T_i1)
        end

        % Value changed function: NodosenelespacioEditField
        function NodosenelespacioEditFieldValueChanged(app, event)
            app.n_x = app.NodosenelespacioEditField.Value;
            main2(app.UIAxes, app.UIAxes2, app.L, app.t_max, app.n_x, app.n_t, app.alpha, app.T_1j, app.T_i1)
        end

        % Value changed function: NodoseneltiempoEditField
        function NodoseneltiempoEditFieldValueChanged(app, event)
            app.n_t = app.NodoseneltiempoEditField.Value;
            main2(app.UIAxes, app.UIAxes2, app.L, app.t_max, app.n_x, app.n_t, app.alpha, app.T_1j, app.T_i1)
        end

        % Value changed function: ConstantedelmaterialEditField
        function ConstantedelmaterialEditFieldValueChanged(app, event)
            app.alpha = app.ConstantedelmaterialEditField.Value;
            main2(app.UIAxes, app.UIAxes2, app.L, app.t_max, app.n_x, app.n_t, app.alpha, app.T_1j, app.T_i1)
        end

        % Value changed function: TemperaturadelcontornoSlider
        function TemperaturadelcontornoSliderValueChanged(app, event)
            app.T_1j = app.TemperaturadelcontornoSlider.Value;
            main2(app.UIAxes, app.UIAxes2, app.L, app.t_max, app.n_x, app.n_t, app.alpha, app.T_1j, app.T_i1)
        end

        % Value changed function: TemperaturainicialSlider
        function TemperaturainicialSliderValueChanged(app, event)
            app.T_i1 = app.TemperaturainicialSlider.Value;
            main2(app.UIAxes, app.UIAxes2, app.L, app.t_max, app.n_x, app.n_t, app.alpha, app.T_1j, app.T_i1)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {630, 630};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {359, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LongituddelasuperficieSliderLabel = uilabel(app.LeftPanel);
            app.LongituddelasuperficieSliderLabel.Text = 'Longitud de la superficie';
            app.LongituddelasuperficieSlider = uislider(app.LeftPanel);
            app.LongituddelasuperficieSlider.Limits = [0 10];
            app.LongituddelasuperficieSlider.ValueChangedFcn = createCallbackFcn(app, @LongituddelasuperficieSliderValueChanged, true);
            app.LongituddelasuperficieSlider.Value = 1;
            app.TiempototalSliderLabel = uilabel(app.LeftPanel);
            app.TiempototalSliderLabel.Text = 'Tiempo total';
            app.TiempototalSlider = uislider(app.LeftPanel);
            app.TiempototalSlider.Limits = [0 10];
            app.TiempototalSlider.ValueChangedFcn = createCallbackFcn(app, @TiempototalSliderValueChanged, true);
            app.TiempototalSlider.Value = 2;
            app.NodosenelespacioEditFieldLabel = uilabel(app.LeftPanel);
            app.NodosenelespacioEditFieldLabel.Text = 'Nodos en el espacio';
            app.NodosenelespacioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NodosenelespacioEditField.Limits = [5 10001];
            app.NodosenelespacioEditField.ValueChangedFcn = createCallbackFcn(app, @NodosenelespacioEditFieldValueChanged, true);
            app.NodosenelespacioEditField.Placeholder = '11';
            app.NodosenelespacioEditField.Value = 11;
            app.NodoseneltiempoEditFieldLabel = uilabel(app.LeftPanel);
            app.NodoseneltiempoEditFieldLabel.Text = 'Nodos en el tiempo';
            app.NodoseneltiempoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NodoseneltiempoEditField.Limits = [2 20];
            app.NodoseneltiempoEditField.ValueChangedFcn = createCallbackFcn(app, @NodoseneltiempoEditFieldValueChanged, true);
            app.NodoseneltiempoEditField.Placeholder = '9';
            app.NodoseneltiempoEditField.Value = 9;
            app.ConstantedelmaterialEditFieldLabel = uilabel(app.LeftPanel);
            app.ConstantedelmaterialEditFieldLabel.Text = 'Constante del material';
            app.ConstantedelmaterialEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ConstantedelmaterialEditField.ValueChangedFcn = createCallbackFcn(app, @ConstantedelmaterialEditFieldValueChanged, true);
            app.ConstantedelmaterialEditField.Placeholder = '0.2';
            app.ConstantedelmaterialEditField.Value = 0.2;
            app.TemperaturadelcontornoSliderLabel = uilabel(app.LeftPanel);
            app.TemperaturadelcontornoSliderLabel.Text = 'Temperatura del contorno';
            app.TemperaturadelcontornoSlider = uislider(app.LeftPanel);
            app.TemperaturadelcontornoSlider.Limits = [0 200];
            app.TemperaturadelcontornoSlider.ValueChangedFcn = createCallbackFcn(app, @TemperaturadelcontornoSliderValueChanged, true);
            app.TemperaturadelcontornoSlider.Value = 70;
            app.TemperaturainicialSliderLabel = uilabel(app.LeftPanel);
            app.TemperaturainicialSliderLabel.Text = 'Temperatura inicial';
            app.TemperaturainicialSlider = uislider(app.LeftPanel);
            app.TemperaturainicialSlider.Limits = [0 200];
            app.TemperaturainicialSlider.ValueChangedFcn = createCallbackFcn(app, @TemperaturainicialSliderValueChanged, true);
            app.TemperaturainicialSlider.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Estado transitorio')
            xlabel(app.UIAxes, 'Posición')
            ylabel(app.UIAxes, 'Temperatura')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Estado estable')
            xlabel(app.UIAxes2, 'Posición')
            ylabel(app.UIAxes2, 'Temperatura')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end